classdef Project4_Reza_Roodi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Label                     matlab.ui.control.Label
        RezaRoodiLabel            matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        SpeedGauge                matlab.ui.control.Gauge
        SpeedmsGaugeLabel         matlab.ui.control.Label
        FrictionSlider            matlab.ui.control.Slider
        FrictionCoeffSliderLabel  matlab.ui.control.Label
        ForceSlider               matlab.ui.control.Slider
        MagForceNSliderLabel      matlab.ui.control.Label
        MassSlider                matlab.ui.control.Slider
        MasskgSliderLabel         matlab.ui.control.Label
        AnimationAxes             matlab.ui.control.UIAxes
    end

methods (Access = private)
    function dydt = train_ode(app, t, y, m, F_mag, mu, g, c)
        v = y(2);
        F_fric = mu * m * g;
        if v == 0 && F_mag <= F_fric
            a = 0;
        else
            a = (F_mag - F_fric - c*v) / m;
        end
        dydt = [v; a];
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            m = app.MassSlider.Value;0
F_mag = app.ForceSlider.Value;
mu = app.FrictionSlider.Value;
g = 9.81;
c = 0.005;

tspan = linspace(0, 3, 100);
y0 = [0; 0];

[~, y] = ode45(@(t,y) app.train_ode(t, y, m, F_mag, mu, g, c), tspan, y0);
pos = y(:,1);
vel = y(:,2);
assignin('base', 'pos', pos);
assignin('base', 'vel', vel);

cla(app.AnimationAxes);
tunnel_length = max(pos) + 0.2;
plot(app.AnimationAxes, [-0.1, tunnel_length], [0.05, 0.05], 'k--', 'LineWidth', 2);
hold(app.AnimationAxes, 'on');
plot(app.AnimationAxes, [-0.1, tunnel_length], [-0.05, -0.05], 'k--', 'LineWidth', 2);
xlim(app.AnimationAxes, [-0.1, tunnel_length]);
ylim(app.AnimationAxes, [-0.2, 0.2]);

train_rect = rectangle(app.AnimationAxes, 'Position', [pos(1), -0.03, 0.15, 0.06], 'Curvature', 0.2, 'FaceColor', [0.7 0.7 0.7], 'LineWidth', 1.5);
mag1 = rectangle(app.AnimationAxes, 'Position', [pos(1)-0.02, -0.03, 0.02, 0.06], 'FaceColor', 'c');
mag2 = rectangle(app.AnimationAxes, 'Position', [pos(1)+0.15, -0.03, 0.02, 0.06], 'FaceColor', 'c');

for i = 1:length(pos)
    cx = pos(i);
    train_rect.Position(1) = cx;
    mag1.Position(1) = cx - 0.02;
    mag2.Position(1) = cx + 0.15;
    app.SpeedGauge.Value = vel(i);
    drawnow;
    pause(0.01);
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnimationAxes = uiaxes(app.UIFigure);
            title(app.AnimationAxes, 'AnimationAxes')
            xlabel(app.AnimationAxes, 'X')
            ylabel(app.AnimationAxes, 'Y')
            zlabel(app.AnimationAxes, 'Z')
            app.AnimationAxes.YTick = [];
            app.MasskgSliderLabel = uilabel(app.UIFigure);
            app.MasskgSliderLabel.Text = 'Mass (kg)';
            app.MassSlider = uislider(app.UIFigure);
            app.MassSlider.Limits = [0.01 0.2];
            app.MassSlider.Value = 0.05;
            app.MagForceNSliderLabel = uilabel(app.UIFigure);
            app.MagForceNSliderLabel.Text = 'Mag Force (N)';
            app.ForceSlider = uislider(app.UIFigure);
            app.ForceSlider.Limits = [0.005 0.1];
            app.ForceSlider.Value = 0.02;
            app.FrictionCoeffSliderLabel = uilabel(app.UIFigure);
            app.FrictionCoeffSliderLabel.Text = 'Friction Coeff';
            app.FrictionSlider = uislider(app.UIFigure);
            app.FrictionSlider.Limits = [0.001 0.05];
            app.FrictionSlider.Value = 0.001;
            app.SpeedmsGaugeLabel = uilabel(app.UIFigure);
            app.SpeedmsGaugeLabel.Text = 'Speed (m/s)';
            app.SpeedGauge = uigauge(app.UIFigure, 'circular');
            app.SpeedGauge.Limits = [0 4];
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run Simulation';
            app.RezaRoodiLabel = uilabel(app.UIFigure);
            app.RezaRoodiLabel.Text = 'Reza Roodi';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '4032202378';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project4_Reza_Roodi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end